classdef Apex_Util < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Apex                            matlab.ui.Figure
        CreateButton                    matlab.ui.control.StateButton
        FlushButton                     matlab.ui.control.StateButton
        ActiveFilesButtonGroup          matlab.ui.container.ButtonGroup
        DeletedFilesButtonGroup         matlab.ui.container.ButtonGroup
        DeleteButton                    matlab.ui.control.StateButton
        ReadButton                      matlab.ui.control.StateButton
        RecoverButton                   matlab.ui.control.StateButton
        LogsTextAreaLabel               matlab.ui.control.Label
        LogsTextArea                    matlab.ui.control.TextArea
        Logo                            matlab.ui.control.UIAxes
        ShikharTuliShreshthTuliLabel_3  matlab.ui.control.Label
        ShikharTuliShreshthTuliLabel_2  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ApexMemoryTab                   matlab.ui.container.Tab
        StubMemoryTab                   matlab.ui.container.Tab
        RecoverButton_2                 matlab.ui.control.StateButton
        ApexmemoryLabel                 matlab.ui.control.Label
        StubmemoryLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        mem;
        stubmem;
        cmap;
        o;
        colors;
        files;
        activefiles;
        activecolors;
        deletedcolors;
        deletedfiles;
        af_buttons = [];
        df_buttons = [];
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Visible = 'off';
            app.ActiveFilesButtonGroup.Visible = 'off';
            app.DeletedFilesButtonGroup.Visible = 'off';
            app.DeleteButton.Visible = 'off';
            app.CreateButton.Visible = 'off';
            app.FlushButton.Visible = 'off';
            app.ReadButton.Visible = 'off';
            app.RecoverButton.Visible = 'off';
            app.LogsTextArea.Visible = 'off';
            app.LogsTextAreaLabel.Visible = 'off';
            app.ShikharTuliShreshthTuliLabel_2.Visible = 'off';
            app.ShikharTuliShreshthTuliLabel_3.Visible = 'off';
            app.ApexmemoryLabel.Visible = 'off';
            app.StubmemoryLabel.Visible = 'off';
            app.RecoverButton_2.Visible = 'off';
            f = app.Apex;
            d = uiprogressdlg(f,'Title','Please select Apex Java Code Directory', 'Message','Waiting for response', 'Indeterminate','on');
            path = uigetdir('Select Apex Java Code Directory');
            cd(path);
            cd '..\..\..\src\Apex-Utility';
            close(d);
            d = uiprogressdlg(f,'Title','Please Wait', 'Message','Creating new Apex File System', 'Indeterminate','on');
            delete('.\ApexDir.ser')
            delete('.\StubDir.ser')
            %app.cmap = [0,0,0;0.0156250000000000,0.0156250000000000,0.0156250000000000;0.0312500000000000,0.0312500000000000,0.0312500000000000;0.0468750000000000,0.0468750000000000,0.0468750000000000;0.0625000000000000,0.0625000000000000,0.0625000000000000;0.0781250000000000,0.0781250000000000,0.0781250000000000;0.0937500000000000,0.0937500000000000,0.0937500000000000;0.109375000000000,0.109375000000000,0.109375000000000;0.125000000000000,0.125000000000000,0.125000000000000;0.140625000000000,0.140625000000000,0.140625000000000];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
            app.cmap = [0,0,0;0.0156250000000000,0.0156250000000000,0.0156250000000000;0.0312500000000000,0.0312500000000000,0.0312500000000000;0.0468750000000000,0.0468750000000000,0.0468750000000000;0.0625000000000000,0.0625000000000000,0.0625000000000000;0.0781250000000000,0.0781250000000000,0.0781250000000000;0.0937500000000000,0.0937500000000000,0.0937500000000000;0.109375000000000,0.109375000000000,0.109375000000000;0.125000000000000,0.125000000000000,0.125000000000000;0.140625000000000,0.140625000000000,0.140625000000000];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
            %app.mem = [1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30;1,1,30,30,10,10,40,40,40,40,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,-10,-30];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
            app.mem = zeros(64);
            app.stubmem = zeros(64);            
            %img = imread('C:\Users\user\Desktop\Apex\n_apex\src\Apex-Utility\MemHeatmap.png');
            app.TabGroup.Visible = 'on';
            app.ActiveFilesButtonGroup.Visible = 'on';
            app.DeletedFilesButtonGroup.Visible = 'on';
            app.DeleteButton.Visible = 'on';
            app.CreateButton.Visible = 'on';
            app.FlushButton.Visible = 'on';
            app.ReadButton.Visible = 'on';
            app.LogsTextArea.Visible = 'on';
            app.RecoverButton.Visible = 'on';
            app.LogsTextAreaLabel.Visible = 'on';
            app.ShikharTuliShreshthTuliLabel_2.Visible = 'on';
            app.ShikharTuliShreshthTuliLabel_3.Visible = 'on';
            app.ApexmemoryLabel.Visible = 'on';
            app.StubmemoryLabel.Visible = 'on';
            app.RecoverButton_2.Visible = 'on';
            imshow('.\APEX Logo.PNG', 'Parent', app.Logo);
            %imshow(img, 'Parent', app.Logo);
            heatmap(app.ApexMemoryTab, app.mem, 'Colormap', app.cmap, 'Colorlimits', [-64 64], 'ColorbarVisible', 'off', 'CellLabelColor', 'none', 'Fontsize', 5);
            heatmap(app.StubMemoryTab, app.stubmem, 'Colormap', app.cmap, 'Colorlimits', [-64 64], 'ColorbarVisible', 'off', 'CellLabelColor', 'none', 'Fontsize', 5);
            javaaddpath(path);
            app.o = ApexUtil;
            app.o.main([]);
            app.o.dumpMemory;
            app.o.dumpStubMemory;
            close(d);
            app.LogsTextArea.Value = char(app.o.Logs);
        end

        % Value changed function: CreateButton
        function CreateButtonValueChanged(app, event)
            value = app.CreateButton.Value;
            [file, path] = uigetfile('*.*', 'Select file to create in Apex Directory');
            app.CreateButton.Value = 0;
            app.o.create(strcat(path, file), file);
            app.CreateButton.Value = 0;
            app.activecolors =  app.o.printMemoryFilesColors();
            app.activefiles = app.o.printMemoryFiles();
            for i = 1:1:length(app.af_buttons)
                delete(app.af_buttons(i));
            end
            app.af_buttons = [];
            for i = 1:1:length(app.activefiles)
                b = uiradiobutton(app.ActiveFilesButtonGroup);
                b.Text = char(app.activefiles(i));
                b.FontColor = app.cmap(app.activecolors(i) + 65, :);
                b.Position = [11 (266 - (i-1)*22) 150 22];
                b.Value = true;
                app.af_buttons = [app.af_buttons; b];
            end
            app.mem = app.o.getMem();
            app.stubmem = app.o.getStub();
            %img = imread('C:\Users\user\Desktop\Apex\n_apex\src\Apex-Utility\MemHeatmap.png');
            %imshow(img, 'Parent', app.Logo);
            heatmap(app.ApexMemoryTab, app.mem, 'Colormap', app.cmap, 'Colorlimits', [-64 64], 'ColorbarVisible', 'off', 'CellLabelColor', 'none', 'Fontsize', 5);
            heatmap(app.StubMemoryTab, app.stubmem, 'Colormap', app.cmap, 'Colorlimits', [-64 64], 'ColorbarVisible', 'off', 'CellLabelColor', 'none', 'Fontsize', 5);
            app.LogsTextArea.Value = char(app.o.Logs);
        end

        % Value changed function: DeleteButton
        function DeleteButtonValueChanged(app, event)
            value = app.DeleteButton.Value;
            app.DeleteButton.Value = 0;
            for i = 1:1:length(app.activefiles)
                if app.af_buttons(i).Value == true
                    app.o.delete(app.af_buttons(i).Text);
                end
            end
            for i = 1:1:length(app.af_buttons)
                delete(app.af_buttons(i));
            end
            app.activecolors =  app.o.printMemoryFilesColors();
            app.activefiles = app.o.printMemoryFiles();
            app.af_buttons = [];
            for i = 1:1:length(app.activefiles)
                b = uiradiobutton(app.ActiveFilesButtonGroup);
                b.Text = char(app.activefiles(i));
                b.FontColor = app.cmap(app.activecolors(i) + 65, :);
                b.Position = [11 (266 - (i-1)*22) 150 22];
                b.Value = true;
                app.af_buttons = [app.af_buttons; b];
            end
            for i = 1:1:length(app.df_buttons)
                delete(app.df_buttons(i));
            end
            app.df_buttons = [];
            app.deletedcolors = app.o.printDeletedFilesColors();
            app.deletedfiles = app.o.printDeletedFiles();
            for i = 1:1:length(app.deletedfiles)
                b = uiradiobutton(app.DeletedFilesButtonGroup);
                b.Text = char(app.deletedfiles(i));
                b.FontColor = app.cmap(app.deletedcolors(i) + 65, :);
                b.Position = [11 (266 - (i-1)*22) 150 22];
                b.Value = true;
                app.df_buttons = [app.df_buttons; b];
            end
            app.mem = app.o.getMem();
            app.stubmem = app.o.getStub();
            %img = imread('C:\Users\user\Desktop\Apex\n_apex\src\Apex-Utility\MemHeatmap.png');
            %imshow(img, 'Parent', app.Logo);
            heatmap(app.ApexMemoryTab, app.mem, 'Colormap', app.cmap, 'Colorlimits', [-64 64], 'ColorbarVisible', 'off', 'CellLabelColor', 'none', 'Fontsize', 5);
            heatmap(app.StubMemoryTab, app.stubmem, 'Colormap', app.cmap, 'Colorlimits', [-64 64], 'ColorbarVisible', 'off', 'CellLabelColor', 'none', 'Fontsize', 5);
            app.LogsTextArea.Value = char(app.o.Logs);
        end

        % Value changed function: RecoverButton
        function RecoverButtonValueChanged(app, event)
            value = app.RecoverButton.Value;
            path = uigetdir('Select target recovery folder');
            app.RecoverButton.Value = 0;
            for i = 1:1:length(app.deletedfiles)
                if app.df_buttons(i).Value == true
                    app.o.recover(strcat(path, '\'), app.df_buttons(i).Text);
                end
            end
            app.LogsTextArea.Value = char(app.o.Logs);
        end

        % Value changed function: ReadButton
        function ReadButtonValueChanged(app, event)
            value = app.ReadButton.Value;
            path = uigetdir('Select Target read folder');
            app.ReadButton_2.Value = 0;
            for i = 1:1:length(app.activefiles)
                if app.af_buttons(i).Value == true
                    app.o.read(strcat(path, '\'), app.af_buttons(i).Text);
                end
            end
            app.LogsTextArea.Value = char(app.o.Logs);
        end

        % Value changed function: FlushButton
        function FlushButtonValueChanged(app, event)
            value = app.FlushButton.Value;
            app.FlushButton.Value = 0;
            app.o.dumpMemory;
            app.o.dumpStubMemory;
            app.LogsTextArea.Value = char(app.o.Logs);
        end

        % Value changed function: RecoverButton_2
        function RecoverButton_2ValueChanged(app, event)
            value = app.RecoverButton.Value;
            path = uigetdir('Select target recovery folder');
            app.RecoverButton.Value = 0;
            for i = 1:1:length(app.deletedfiles)
                if app.df_buttons(i).Value == true
                    app.o.recoverStub(strcat(path, '\'), app.df_buttons(i).Text);
                end
            end
            app.LogsTextArea.Value = char(app.o.Logs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Apex = uifigure;
            app.Apex.Name = 'Apex Utility';
            app.CreateButton = uibutton(app.Apex, 'state');
            app.CreateButton.ValueChangedFcn = createCallbackFcn(app, @CreateButtonValueChanged, true);
            app.CreateButton.Text = 'Create';
            app.FlushButton = uibutton(app.Apex, 'state');
            app.FlushButton.ValueChangedFcn = createCallbackFcn(app, @FlushButtonValueChanged, true);
            app.FlushButton.Text = 'Flush';
            app.ActiveFilesButtonGroup = uibuttongroup(app.Apex);
            app.ActiveFilesButtonGroup.Title = 'Active Files';
            app.DeletedFilesButtonGroup = uibuttongroup(app.Apex);
            app.DeletedFilesButtonGroup.Title = 'Deleted Files';
            app.DeleteButton = uibutton(app.Apex, 'state');
            app.DeleteButton.ValueChangedFcn = createCallbackFcn(app, @DeleteButtonValueChanged, true);
            app.DeleteButton.Text = 'Delete';
            app.ReadButton = uibutton(app.Apex, 'state');
            app.ReadButton.ValueChangedFcn = createCallbackFcn(app, @ReadButtonValueChanged, true);
            app.ReadButton.Text = 'Read';
            app.RecoverButton = uibutton(app.Apex, 'state');
            app.RecoverButton.ValueChangedFcn = createCallbackFcn(app, @RecoverButtonValueChanged, true);
            app.RecoverButton.Text = 'Recover';
            app.LogsTextAreaLabel = uilabel(app.Apex);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.Apex);
            app.Logo = uiaxes(app.Apex);
            app.Logo.XTick = [];
            app.Logo.YTick = [];
            app.ShikharTuliShreshthTuliLabel_3 = uilabel(app.Apex);
            app.ShikharTuliShreshthTuliLabel_3.Text = '© Shikhar Tuli, Shreshth Tuli';
            app.ShikharTuliShreshthTuliLabel_2 = uilabel(app.Apex);
            app.ShikharTuliShreshthTuliLabel_2.Text = {'Apache - 2.0 License: Unless required by applicable law or'; 'agreed to in writing software distributed under the License is'; 'distributed on an "AS IS" BASIS WITHOUT WARRANTIES OR'; 'CONDITIONS OF ANY KIND either express or implied.'};
            app.TabGroup = uitabgroup(app.Apex);
            app.ApexMemoryTab = uitab(app.TabGroup);
            app.ApexMemoryTab.Title = 'Apex Memory';
            app.StubMemoryTab = uitab(app.TabGroup);
            app.StubMemoryTab.Title = 'Stub Memory';
            app.RecoverButton_2 = uibutton(app.Apex, 'state');
            app.RecoverButton_2.ValueChangedFcn = createCallbackFcn(app, @RecoverButton_2ValueChanged, true);
            app.RecoverButton_2.Text = 'Recover';
            app.ApexmemoryLabel = uilabel(app.Apex);
            app.ApexmemoryLabel.Text = 'Apex memory:';
            app.StubmemoryLabel = uilabel(app.Apex);
            app.StubmemoryLabel.Text = 'Stub memory:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Apex_Util

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Apex)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Apex)
        end
    end
end